classdef BVP_Real_Axis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OutputPanel                    matlab.ui.container.Panel
        SaveButton                     matlab.ui.control.Button
        EvaluateButton                 matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        ExecutiontimeforevaluatingLabel  matlab.ui.control.Label
        ExecutiontimeforplottingLabel  matlab.ui.control.Label
        f_512x_0Label                  matlab.ui.control.Label
        Solutionattheevaluationpoint__________Label  matlab.ui.control.Label
        rmcondA_mLabel                 matlab.ui.control.Label
        e_mfLabel                      matlab.ui.control.Label
        Conditionnumberandmaximumabsoluteerror__Label  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        InputPanel                     matlab.ui.container.Panel
        ResetButton                    matlab.ui.control.Button
        x_0EditField                   matlab.ui.control.NumericEditField
        x_0EditFieldLabel              matlab.ui.control.Label
        Evaluationpoint_______________Label  matlab.ui.control.Label
        NEditField                     matlab.ui.control.NumericEditField
        NEditFieldLabel                matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        bLabel                         matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        aLabel                         matlab.ui.control.Label
        Intervalsendpointsandnumberofpoints____Label  matlab.ui.control.Label
        Inputfunctionsandparameter________Label  matlab.ui.control.Label
        muEditField                    matlab.ui.control.NumericEditField
        muEditFieldLabel               matlab.ui.control.Label
        hxEditField                    matlab.ui.control.EditField
        hxLabel                        matlab.ui.control.Label
        axEditField                    matlab.ui.control.EditField
        axLabel                        matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        equation                       matlab.ui.control.Label
        NumericalresolutionofBoundaryValueProblemsLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Default values
            app.axEditField.Value = ''; % Empty
            app.hxEditField.Value = ''; % Empty
            app.muEditField.Value = 0;
            app.aEditField.Value = 0;
            app.bEditField.Value = 0;
            app.NEditField.Value = 100;
            app.x_0EditField.Value = 0;
            
            eqStr = "$$ \begin{cases} f''(x) - \mu a(x) f(x)= h(x) \\ " + ...
                "\!\!\!\!\!\!\!\!\!\!\!\!\!\!\!\!\!\!\!\!\!\!\!\!\!\!\!\!\! f(\pm\infty) = 0 \end{cases} $$";
 
            % Update the equation label
            app.equation.Text = eqStr;
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
        try
            % Read the data entered by the user
            aFunStr = app.axEditField.Value;
            hFunStr = app.hxEditField.Value;
            mu = app.muEditField.Value;
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            N = app.NEditField.Value;
        
            % Alert mu=0
            if mu == 0
                uialert(app.UIFigure,'$\mu$ cannot be 0','Error');
                return;  % Exit
            end

            % Alert N negative or non integer
            if N<=0 || mod(N,1)~=0
                uialert(app.UIFigure,'N must be a positive integer. Default value N = 100','Warning');
                app.NEditField.Value=100;
            end

            % Converts the functions a(x) and h(x) to MATLAB functions
            aFun = str2func(['@(x)', aFunStr]);
            hFun = str2func(['@(x)', hFunStr]);

            xTest = [0 1];
            try
            aFun(xTest);
            catch ME
                if contains(ME.message, 'Matrix dimensions must agree') || ...
                        contains(ME.message, 'Inner matrix dimensions must agree') || ...
                        contains(ME.message, 'Incorrect dimensions for raising a matrix')
    
                    uialert(app.UIFigure, "Use element-wise operators: .*, ./, .^ for function a(x)",'Error');
                else
                    uialert(app.UIFigure, ...
                        "Error in evaluating function a(x): " + ME.message, ...
                        " Error");
                end
                return;
            end
         
            try
            hFun(xTest);
            catch ME
                if contains(ME.message, 'Matrix dimensions must agree') || ...
                        contains(ME.message, 'Inner matrix dimensions must agree') || ...
                        contains(ME.message, 'Incorrect dimensions for raising a matrix')
    
                    uialert(app.UIFigure, "Use element-wise operators: .*, ./, .^ for function h(x)",'Error');
                else
                    uialert(app.UIFigure, ...
                        "Error in evaluating function h(x): " + ME.message, ...
                        " Error");
                end
                return;
            end
            % Constructing the function A(X)= (mu a(x)-1)*exp(x^2/2)
            AFun = @(x) (mu*aFun(x)-1).*exp(x.^2/2);
            
            % Alert [a,b]
            if a==b || a>b
                uialert(app.UIFigure, 'b must be grater than a. The plot cannot be generated.', 'Error');
                return;  % Exit
            end
         
            % Create a vector x for the interval [a, b] with N equally 
            % spaced points
            t = linspace(a, b, N);

            % Compute the solutions
            [f0,~,~] = NystromMethodFIE(600,2,hFun,AFun,t);
            
            % Control if f0=NaN
            if isnan(f0)
               uialert(app.UIFigure,'The method is not applicable','Error');
               return;  % Exit 
            end
            t1=tic;
            [fm,C,~] = NystromMethodFIE(512,2,hFun,AFun,t);
            
            % Control of the condition number of the solved linear system 
            if C > 1/eps
                msg = sprintf('Results may be inaccurate: Cond(A_m) = %.4e', C);
                uialert(app.UIFigure, msg, 'Warning');
            end

            % Compute the row of absolute errors
            err = abs(f0-fm);
            % Compute the maximum of absolute errors
            Merr = max(err);
            % Control of the convergence of the method using the weigthed 
            % absolute error
            if Merr > 1
                uialert(app.UIFigure,'The method is not convergent','Error');
                return;  % Exit
            end

            % Results
            app.rmcondA_mLabel.Text = sprintf('$Cond(A_{m})=$ %.4e', C);
            app.e_mfLabel.Text = sprintf('$e_m(f)=$ %.2e', Merr);

            % Plot of Nystrom interpolating function
            plot(app.UIAxes, t(1:end), fm(1:end),'-k','linewidth',1.5)
            title(app.UIAxes, 'Plot of solution of BVP');
            xlabel(app.UIAxes, 'x');
            xlim(app.UIAxes,[t(1),t(end)]);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {};
            app.UIAxes.XTickMode = 'auto';
            app.UIAxes.XTickLabelMode = 'auto';
            ylabel(app.UIAxes,'f_{512}(x)');
            ylim(app.UIAxes,[min(fm(1:end)),max(fm(1:end))]);

            exTime = toc(t1);
            app.ExecutiontimeforplottingLabel.Text = sprintf('Execution time for plotting: %.4f s', exTime);
        catch ME
            % Mostra l'errore in un popup nella GUI
            uialert(app.UIFigure, ME.message, 'Error');
        end
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
        try
            aFunStr = app.axEditField.Value;
            hFunStr = app.hxEditField.Value;
            mu = app.muEditField.Value;
            x0 = app.x_0EditField.Value;
            
            % Alert mu=0
            if mu == 0
                uialert(app.UIFigure,'The value of mu cannot be 0','Error');
                return;  % Exit
            end

            % Converts the functions a(x) and h(x) to MATLAB functions
            aFun = str2func(['@(x)', aFunStr]);
            hFun = str2func(['@(x)', hFunStr]);

            % Constructing the function A(X)= (mu a(x)-1)*exp(x^2/2)
            AFun = @(x) (mu*aFun(x)-1).*exp(x.^2/2);

            % Compute the solutions in x0
            [f0,~,~] = NystromMethodFIE(600,2,hFun,AFun,x0);
            
            if isnan(f0)
                uialert(app.UIFigure,'The method is not applicable','Error');
                return;  % Exit
            end
            t2=tic;
            [fm,C,~] = NystromMethodFIE(512,2,hFun,AFun,x0);

            % Control of the condition number of the solved linear system 
            if C > 1/eps
                msg = sprintf('Results may be inaccurate: Cond(A_m) = %.4e', C);
                uialert(app.UIFigure, msg, 'Warning');
            end

             % Compute the absolute errors in x0
            err = abs(f0-fm);

            % Control of the convergence of the method using the weigthed 
            % absolute error
            if err > 1
                uialert(app.UIFigure,'The method is not convergent','Error');
                return;  % Exit
            end

            % Results in x0
            app.rmcondA_mLabel.Text = sprintf('$Cond(A_{m})=$ %.4e', C);
            app.e_mfLabel.Text = sprintf('$e_m(f)=$ %.2e', err);
            x0_str = num2str(x0);
            app.f_512x_0Label.Text = sprintf('$f_{512}(%s) = %.16e$', x0_str, fm);
            exTimeEv = toc(t2);
            app.ExecutiontimeforevaluatingLabel.Text = sprintf('Execution time for evaluating: %.4f s', exTimeEv);
        catch ME
            % Mostra l'errore in un popup nella GUI
            uialert(app.UIFigure, ME.message, 'Error');
        end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.axEditField.Value = ''; % Empty
            app.hxEditField.Value = ''; % Empty
            app.muEditField.Value = 0;
            app.aEditField.Value = 0;
            app.bEditField.Value = 0;
            app.NEditField.Value = 100;
            app.x_0EditField.Value = 0;

            app.ExecutiontimeforplottingLabel.Text = 'Execution time for plotting';
            app.rmcondA_mLabel.Text = '$\rm{cond(A_{m})}$';
            app.e_mfLabel.Text = '$e_{m}(f)$';
            app.f_512x_0Label.Text = '$f_{512}(x_{0})$';
            app.ExecutiontimeforevaluatingLabel.Text = 'Execution time for evaluating';
            cla(app.UIAxes);           % Clear axes
             xlim(app.UIAxes,[0,1]);
            app.UIAxes.XTick = [0 1];
            app.UIAxes.XTickLabel = {'a','b'};
%             reset(app.UIAxes);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % As to save the plot
            [file, path] = uiputfile({'*.eps';'*.jpg';'*.png'; '*.pdf'}, 'Save as');

            if ischar(file)
                fullPath = fullfile(path, file);

                fig = figure('Visible', 'off', 'Color', 'white');

                axCopy = copyobj(app.UIAxes, fig);

                set(axCopy, 'Units', 'normalized', 'Position', [0.09 0.12 0.76 0.76]);

                % Save plot
                exportgraphics(fig, fullPath, 'Resolution', 300);

                close(fig);
                
                uialert(app.UIFigure, 'Plot saved successfully!', 'Save completed');

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.NumericalresolutionofBoundaryValueProblemsLabel = uilabel(app.UIFigure);
            app.NumericalresolutionofBoundaryValueProblemsLabel.Text = 'Numerical resolution of Boundary Value Problems';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.Panel = uipanel(app.InputPanel);
            app.equation = uilabel(app.Panel);
            app.equation.Interpreter = 'latex';
            app.equation.Text = '';
            app.axLabel = uilabel(app.InputPanel);
            app.axLabel.Interpreter = 'latex';
            app.axLabel.Text = '$a(x)$';
            app.axEditField = uieditfield(app.InputPanel, 'text');
            app.axEditField.Value = 'exp(-3*x.^2/7)+1';
            app.hxLabel = uilabel(app.InputPanel);
            app.hxLabel.Interpreter = 'latex';
            app.hxLabel.Text = '$h(x)$';
            app.hxEditField = uieditfield(app.InputPanel, 'text');
            app.hxEditField.Value = '1/25.*exp(-((37.*x.^2)/70)).*(-25+exp((3.*x.^2)/7).*(-30+x.^2))';
            app.muEditFieldLabel = uilabel(app.InputPanel);
            app.muEditFieldLabel.Interpreter = 'latex';
            app.muEditFieldLabel.Text = '$\mu$';
            app.muEditField = uieditfield(app.InputPanel, 'numeric');
            app.muEditField.Value = 1;
            app.Inputfunctionsandparameter________Label = uilabel(app.InputPanel);
            app.Inputfunctionsandparameter________Label.Text = '________ Input  functions and parameter________';
            app.Intervalsendpointsandnumberofpoints____Label = uilabel(app.InputPanel);
            app.Intervalsendpointsandnumberofpoints____Label.Text = '____Interval''s endpoints and number of points____';
            app.aLabel = uilabel(app.InputPanel);
            app.aLabel.Interpreter = 'latex';
            app.aLabel.Text = '$a$';
            app.aEditField = uieditfield(app.InputPanel, 'numeric');
            app.aEditField.Value = -5;
            app.bLabel = uilabel(app.InputPanel);
            app.bLabel.Interpreter = 'latex';
            app.bLabel.Text = '$b$';
            app.bEditField = uieditfield(app.InputPanel, 'numeric');
            app.bEditField.Value = 5;
            app.NEditFieldLabel = uilabel(app.InputPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.InputPanel, 'numeric');
            app.NEditField.ValueDisplayFormat = '%.0f';
            app.NEditField.Value = 100;
            app.Evaluationpoint_______________Label = uilabel(app.InputPanel);
            app.Evaluationpoint_______________Label.Text = '______________Evaluation point_______________';
            app.x_0EditFieldLabel = uilabel(app.InputPanel);
            app.x_0EditFieldLabel.Interpreter = 'latex';
            app.x_0EditFieldLabel.Text = '$x_0$';
            app.x_0EditField = uieditfield(app.InputPanel, 'numeric');
            app.x_0EditField.Value = 1.3;
            app.ResetButton = uibutton(app.InputPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output';
            app.UIAxes = uiaxes(app.OutputPanel);
            title(app.UIAxes, 'Plot of the solution in [a,b]')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'f_{m}(x)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 1];
            app.UIAxes.XTickLabel = {'a'; 'b'};
            app.UIAxes.YTick = [];
            app.Conditionnumberandmaximumabsoluteerror__Label = uilabel(app.OutputPanel);
            app.Conditionnumberandmaximumabsoluteerror__Label.Text = '__ Condition number and maximum absolute error__';
            app.e_mfLabel = uilabel(app.OutputPanel);
            app.e_mfLabel.Interpreter = 'latex';
            app.e_mfLabel.Text = '$e_{m}(f)$';
            app.rmcondA_mLabel = uilabel(app.OutputPanel);
            app.rmcondA_mLabel.Interpreter = 'latex';
            app.rmcondA_mLabel.Text = '$\rm{cond}(A_m)$';
            app.Solutionattheevaluationpoint__________Label = uilabel(app.OutputPanel);
            app.Solutionattheevaluationpoint__________Label.Text = '_________Solution at the evaluation point__________';
            app.f_512x_0Label = uilabel(app.OutputPanel);
            app.f_512x_0Label.Interpreter = 'latex';
            app.f_512x_0Label.Text = '$f_{512}(x_{0})$';
            app.ExecutiontimeforplottingLabel = uilabel(app.OutputPanel);
            app.ExecutiontimeforplottingLabel.Text = 'Execution time for plotting';
            app.ExecutiontimeforevaluatingLabel = uilabel(app.OutputPanel);
            app.ExecutiontimeforevaluatingLabel.Text = 'Execution time for evaluating ';
            app.PlotButton = uibutton(app.OutputPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.EvaluateButton = uibutton(app.OutputPanel, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.SaveButton = uibutton(app.OutputPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BVP_Real_Axis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end